classdef Copy_of_data_acquisition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        Tab                          matlab.ui.container.Tab
        StopDisplayButton            matlab.ui.control.StateButton
        StartdisplayButton           matlab.ui.control.Button
        UIAxes_8                     matlab.ui.control.UIAxes
        UIAxes_7                     matlab.ui.control.UIAxes
        UIAxes_6                     matlab.ui.control.UIAxes
        UIAxes_5                     matlab.ui.control.UIAxes
        UIAxes_4                     matlab.ui.control.UIAxes
        UIAxes_3                     matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
        Tab2                         matlab.ui.container.Tab
        deg_315EditField             matlab.ui.control.NumericEditField
        degLabel_3                   matlab.ui.control.Label
        deg_225EditField             matlab.ui.control.NumericEditField
        degLabel_2                   matlab.ui.control.Label
        deg_135EditField             matlab.ui.control.NumericEditField
        degLabel                     matlab.ui.control.Label
        LoadMVCButton                matlab.ui.control.Button
        deg_45EditField              matlab.ui.control.NumericEditField
        degEditFieldLabel            matlab.ui.control.Label
        RDeviationMVCEditField       matlab.ui.control.NumericEditField
        RDeviationMVCEditFieldLabel  matlab.ui.control.Label
        SaveButton                   matlab.ui.control.Button
        UDeviationMVCEditField       matlab.ui.control.NumericEditField
        UDeviationMVCEditFieldLabel  matlab.ui.control.Label
        TextArea_2                   matlab.ui.control.TextArea
        SubjectNameEditField         matlab.ui.control.EditField
        SubjectNameEditFieldLabel    matlab.ui.control.Label
        ExtenMVCEditField_2          matlab.ui.control.NumericEditField
        ExtensionMVCLabel            matlab.ui.control.Label
        FlexionMVCEditField          matlab.ui.control.NumericEditField
        FlexionMVCEditFieldLabel     matlab.ui.control.Label
        MaximumValuntaryContractionGaugeLabel  matlab.ui.control.Label
        EstimateMVCButton            matlab.ui.control.Button
        HandGestureDropDown          matlab.ui.control.DropDown
        HandGestureDropDownLabel     matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        MaximumValuntaryContractionGauge  matlab.ui.control.Gauge
        Tab3                         matlab.ui.container.Tab
        CheckBox_315                 matlab.ui.control.CheckBox
        CheckBox_225                 matlab.ui.control.CheckBox
        CheckBox_135                 matlab.ui.control.CheckBox
        CheckBox_45                  matlab.ui.control.CheckBox
        TextArea_13                  matlab.ui.control.TextArea
        TextArea_12                  matlab.ui.control.TextArea
        TextArea_11                  matlab.ui.control.TextArea
        TextArea_10                  matlab.ui.control.TextArea
        Switch_315                   matlab.ui.control.Switch
        Switch_225                   matlab.ui.control.Switch
        Switch_135                   matlab.ui.control.Switch
        Switch_45                    matlab.ui.control.Switch
        CheckBox_Rest                matlab.ui.control.CheckBox
        CheckBox_R                   matlab.ui.control.CheckBox
        TextArea_7                   matlab.ui.control.TextArea
        ConvertButton                matlab.ui.control.Button
        SaveButton_2                 matlab.ui.control.Button
        TextArea_6                   matlab.ui.control.TextArea
        TextArea_5                   matlab.ui.control.TextArea
        Switch_Rest                  matlab.ui.control.Switch
        DisplayButton                matlab.ui.control.Button
        StartRecordingButton         matlab.ui.control.Button
        CheckBox_U                   matlab.ui.control.CheckBox
        Switch_R                     matlab.ui.control.Switch
        TextArea_4                   matlab.ui.control.TextArea
        CheckBox_E                   matlab.ui.control.CheckBox
        Switch_U                     matlab.ui.control.Switch
        Switch_E                     matlab.ui.control.Switch
        CheckBox_F                   matlab.ui.control.CheckBox
        TextArea_3                   matlab.ui.control.TextArea
        Switch_F                     matlab.ui.control.Switch
        TextArea_9                   matlab.ui.control.TextArea
        TextArea_8                   matlab.ui.control.TextArea
        HandGestureDropDown_2        matlab.ui.control.DropDown
        HandGestureDropDown_2Label   matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        Image_2                      matlab.ui.control.Image
        UIAxes2                      matlab.ui.control.UIAxes
        Tab4                         matlab.ui.container.Tab
        UIAxes_16                    matlab.ui.control.UIAxes
        UIAxes_15                    matlab.ui.control.UIAxes
        UIAxes_14                    matlab.ui.control.UIAxes
        UIAxes_13                    matlab.ui.control.UIAxes
        UIAxes_12                    matlab.ui.control.UIAxes
        UIAxes_11                    matlab.ui.control.UIAxes
        UIAxes_10                    matlab.ui.control.UIAxes
        UIAxes_9                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description

    end
    
    properties (Access = public)
           flexionMVC    = 0;
           extensionMVC  = 0;
           RDeviationMVC = 0;
           UDeviationMVC = 0;
           esample       = [];
           whole_0       = [];
           whole_45      = [];
           whole_90      = [];
           whole_135     = [];
           whole_180     = [];
           whole_225     = [];
           whole_270     = [];
           whole_315     = [];
           whole_360     = [];

    end
    
    methods (Access = public)

        function plotQ(app)
            myx = [0, 2,   3,   8, 9,11];
            myy = [0, 0, 0.4, 0.4, 0, 0];
            my2 = [0, 0, 0.6, 0.6, 0, 0];
            my3 = [0, 0, 0.2, 0.2, 0, 0];
            
            hold(app.UIAxes2,"on");
            plot(app.UIAxes2,myx,myy);app.UIAxes2.YLim = [0, 1.5];
            plot(app.UIAxes2,myx,my2);app.UIAxes2.YLim = [0, 1.5];
            %plot(app.UIAxes2,myx,my3);app.UIAxes2.YLim = [0, 1.5];

            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: HandGestureDropDown
        function HandGestureDropDownValueChanged(app, event)
            switch app.HandGestureDropDown.Value
                case 'Flexion'
                    app.Image.ImageSource = "\Users\lab-admin\Desktop\Rebecca\flexion.jpg";
                case 'Extension'
                    app.Image.ImageSource = "\Users\lab-admin\Desktop\Rebecca\extention.jpg";
                case 'Ulnar Deviation'
                    app.Image.ImageSource = "\Users\lab-admin\Desktop\Rebecca\ulnar_deviation.jpg";
                case 'Radial Deviation'
                    app.Image.ImageSource = "\Users\lab-admin\Desktop\Rebecca\radial_deviation.jpg";
            end

        end

        % Button pushed function: EstimateMVCButton
        function EstimateMVCButtonPushed(app, event)
            Fs = 200; %Hz frequency of Myoband
            maxValue = 0; %MAximum MVC Value meassured until now  
            mm = MyoMex(); %Initialisation of MyoSDK
            m1 = mm.myoData(); % handl to the first and only MyoBand
            pause(0.3);
            for i = 1:50
                e = m1.emg_log; %emglog
                sig = abs(e(end-39:end,:)); %200 ms time window just like the whole system(56 samples)
                meanF = signalTimeFeatureExtractor("Mean", true, 'SampleRate', Fs);
                meanFDS = arrayDatastore(sig,"IterationDimension",2);
                meanFDS = transform(meanFDS,@(x)meanF.extract(x{:}));
                meanFeatures = readall(meanFDS,"UseParallel",true);
                emgMvc= 100 * mean(meanFeatures);
                app.MaximumValuntaryContractionGauge.Value = emgMvc;
                if emgMvc > maxValue
                    maxValue = emgMvc;
                end
                switch app.HandGestureDropDown.Value
                    case 'Flexion'
                        app.FlexionMVCEditField.Value = maxValue;
                    case 'Extension'

                        app.ExtenMVCEditField_2.Value = maxValue;
                    case 'Ulnar Deviation'

                        app.UDeviationMVCEditField.Value = maxValue;
                    case 'Radial Deviation'

                        app.RDeviationMVCEditField.Value = maxValue;
                    case '45 deg'

                        app.deg_45EditField.Value = maxValue;
                    case '135 deg'

                        app.deg_135EditField.Value = maxValue;
                    case '225 deg'

                        app.deg_225EditField.Value = maxValue;
                    case '315 deg'

                        app.deg_315EditField.Value = maxValue;
                end
                pause(0.2);
            end
            app.Tab
            e1 = e(end-2199:end,1);
            e2 = e(end-2199:end,2);
            e3 = e(end-2199:end,3);
            e4 = e(end-2199:end,4);
            e5 = e(end-2199:end,5);
            e6 = e(end-2199:end,6);
            e7 = e(end-2199:end,7);
            e8 = e(end-2199:end,8);
            plot(app.UIAxes,e1);app.UIAxes.YLim = [-1,1];
            plot(app.UIAxes_2,e2);app.UIAxes_2.YLim = [-1,1];
            plot(app.UIAxes_3,e3);app.UIAxes_3.YLim = [-1,1];
            plot(app.UIAxes_4,e4);app.UIAxes_4.YLim = [-1,1];
            plot(app.UIAxes_5,e5);app.UIAxes_5.YLim = [-1,1];
            plot(app.UIAxes_6,e6);app.UIAxes_6.YLim = [-1,1];
            plot(app.UIAxes_7,e7);app.UIAxes_7.YLim = [-1,1];
            plot(app.UIAxes_8,e8);app.UIAxes_8.YLim = [-1,1];
            mm.delete;
            clear('mm');

        end

        % Button pushed function: StartdisplayButton
        function StartdisplayButtonPushed(app, event)
            mm = MyoMex();
            m1 = mm.myoData();

            pause(5);
            %e = m1.emg_log;
            while ~app.StopDisplayButton.Value
                e = m1.emg_log;
                e1 = e(end-599:end,1);
                e2 = e(end-599:end,2);
                e3 = e(end-599:end,3);
                e4 = e(end-599:end,4);
                e5 = e(end-599:end,5);
                e6 = e(end-599:end,6);
                e7 = e(end-599:end,7);
                e8 = e(end-599:end,8);
                plot(app.UIAxes,e1);drawnow
                plot(app.UIAxes_2,e2);drawnow
                plot(app.UIAxes_3,e3);drawnow
                plot(app.UIAxes_4,e4);drawnow
                plot(app.UIAxes_5,e5);drawnow
                plot(app.UIAxes_6,e6);drawnow
                plot(app.UIAxes_7,e7);drawnow
                plot(app.UIAxes_8,e8);drawnow

                app.UIAxes.YLim = [-1,1];
                app.UIAxes_2.YLim = [-1,1];
                app.UIAxes_3.YLim = [-1,1];
                app.UIAxes_4.YLim = [-1,1];
                app.UIAxes_5.YLim = [-1,1];
                app.UIAxes_6.YLim = [-1,1];
                app.UIAxes_7.YLim = [-1,1];
                app.UIAxes_8.YLim = [-1,1];
            end
            if app.StopDisplayButton.Value
                mm.delete;
                clear('mm');
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if app.SubjectNameEditField.Value
                name = app.SubjectNameEditField.Value;
                folder_name = fullfile("\Users\lab-admin\Desktop\Rebecca\" + name);
                %gesture = ["Flexion"; "Extention";"U. Deviation"; "R. Deviation"];
                values = [app.FlexionMVCEditField.Value; app.ExtenMVCEditField_2.Value; app.UDeviationMVCEditField.Value; app.RDeviationMVCEditField.Value;...
                    app.deg_45EditField.Value; app.deg_135EditField.Value; app.deg_225EditField.Value; app.deg_315EditField.Value;];
                MVCTable = values;% table(values,'RowNames',gesture);
                if ~exist(folder_name,"file")
                    mkdir(folder_name);
                end
                
                fileName = fullfile(folder_name + '\' + "MVC_info.mat");
                save(fileName,"MVCTable");
            end
        end

        % Button down function: Tab3
        function Tab3ButtonDown(app, event)
            app.plotQ();
        end

        % Value changed function: HandGestureDropDown_2
        function HandGestureDropDown_2ValueChanged2(app, event)
            %value = app.HandGestureDropDown_2.Value;
            switch app.HandGestureDropDown_2.Value
                case 'Flexion'
                    app.Image_2.ImageSource = "\Users\lab-admin\Desktop\Rebecca\flexion.jpg";
                case 'Extension'
                    app.Image_2.ImageSource = "\Users\lab-admin\Desktop\Rebecca\extention.jpg";
                case 'Ulnar Deviation'
                    app.Image_2.ImageSource = "\Users\lab-admin\Desktop\Rebecca\ulnar_deviation.jpg";
                case 'Radial Deviation'
                    app.Image_2.ImageSource = "\Users\lab-admin\Desktop\Rebecca\radial_deviation.jpg";
                case 'Rest'
                    app.Image_2.Visible = 'off';
                    app.TextArea.Visible = 'on';     
            end
        end

        % Value changed function: ExtenMVCEditField_2
        function ExtenMVCEditField_2ValueChanged(app, event)
            value = app.ExtenMVCEditField_2.Value;
            app.extensionMVC = value;
        end

        % Value changed function: FlexionMVCEditField
        function FlexionMVCEditFieldValueChanged(app, event)
            value = app.FlexionMVCEditField.Value;
            app.flexionMVC = value;
        end

        % Value changed function: RDeviationMVCEditField
        function RDeviationMVCEditFieldValueChanged(app, event)
            value = app.RDeviationMVCEditField.Value;
            app.RDeviationMVC = value;
        end

        % Value changed function: UDeviationMVCEditField
        function UDeviationMVCEditFieldValueChanged(app, event)
            value = app.UDeviationMVCEditField.Value;
            app.UDeviationMVC = value;
        end

        % Button pushed function: StartRecordingButton
        function StartRecordingButtonPushed3(app, event)
            value = app.HandGestureDropDown_2.Value;
            switch value
                case 'Flexion'
                    mvc = app.FlexionMVCEditField.Value;
                case 'Extension'
                    mvc = app.ExtenMVCEditField_2.Value;
                case 'Ulnar Deviation'
                    mvc = app.UDeviationMVCEditField.Value;
                case 'Radial Deviation'
                    mvc = app.RDeviationMVCEditField.Value;
                case '45 deg'
                    mvc = app.deg_45EditField.Value;
                case '135 deg'
                    mvc = app.deg_135EditField.Value;
                case '225 deg'
                    mvc = app.deg_225EditField.Value;
                case '315 deg'
                    mvc = app.deg_315EditField.Value;
                case 'Rest'
                    mvc = 100;
            end
            mm = MyoMex();
            m1 = mm.myoData();
            Fs = 200;
            pause(2);
            sample = [];
            for j = 1:4
                e = m1.emg_log;
                time0 = length(e)/Fs;
                timespace = 0;
                intention = 0;
                app.TextArea.Visible = "off";
                app.plotQ;
                % hold(app.UIAxes2,"on");
                tic
                for i = 1:25
                    e = m1.emg_log;
                    timeSample = length(e)/Fs-time0;
                    timespace = [timespace,toc];
                    sig = abs(e(end-99:end,:)); % 200 ms time window just like the whole system
                    meanF = signalTimeFeatureExtractor("Mean", true, 'SampleRate', Fs);
                    meanFDS = arrayDatastore(sig,"IterationDimension",2);
                    meanFDS = transform(meanFDS,@(x)meanF.extract(x{:}));
                    meanFeatures = readall(meanFDS,"UseParallel",true);
                    meanMVC= mean(meanFeatures);
                    intentionValue = meanMVC*100/mvc;
                    intention = [intention,intentionValue];
                    plotMVC = plot(app.UIAxes2,timespace',intention','b--o',MarkerFaceColor='cyan',LineWidth=2,Color='blue');drawnow
                    pause(0.4);
                end
                cla(app.UIAxes2);
                toc
                e = m1.emg_log;
                sample = [sample;e(end-2400:end,:)];
                hold(app.UIAxes2,"off");
                app.TextArea.Visible = "on";
                pause(3);
            end
            app.esample = sample;

            mm.delete;
            clear('mm');
            
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            value = app.HandGestureDropDown_2.Value;
            
            switch value
                case 'Flexion'
                    labl = '270';
                case 'Extension'
                    labl = '90';
                case 'Ulnar Deviation'
                    labl = '360';
                case 'Radial Deviation'
                    labl = '180';
                case '45 deg'
                    labl = '45';
                case '135 deg'
                    labl = '135';
                case '225 deg'
                    labl = '225';
                case '315 deg'
                    labl = '315';
                case 'Rest'
                    labl = '0';
            end
            
            name = app.SubjectNameEditField.Value;
            data = app.esample;
            folder_name = fullfile("\Users\lab-admin\Desktop\Rebecca\" + name + '\' + labl );
            if ~exist("folder_name","dir")
                mkdir(folder_name)
            end
            if numel(dir(folder_name)) <= 2
                list = 0;
            else
                fds = fileDatastore(folder_name, 'FileExtensions', '.mat','ReadFcn', @importdata );
                list = length(readall(fds));
            end
            sessionNumber = list + 1;
            fileName = fullfile(folder_name + '\' + 'rawData' + 'S' + sessionNumber);
            save(fileName,"data");
        end

        % Button pushed function: DisplayButton
        function DisplayButtonPushed(app, event)

            timesig = linspace(0,length(app.esample)/200,length(app.esample));
            e1 = app.esample(:,1);
            e2 = app.esample(:,2);
            e3 = app.esample(:,3);
            e4 = app.esample(:,4);
            e5 = app.esample(:,5);
            e6 = app.esample(:,6);
            e7 = app.esample(:,7);
            e8 = app.esample(:,8);
            plot(app.UIAxes,timesig,e1);
            plot(app.UIAxes_2,timesig,e2);
            plot(app.UIAxes_3,timesig,e3);
            plot(app.UIAxes_4,timesig,e4);
            plot(app.UIAxes_5,timesig,e5);
            plot(app.UIAxes_6,timesig,e6);
            plot(app.UIAxes_7,timesig,e7);
            plot(app.UIAxes_8,timesig,e8);
            app.UIAxes.YLim =   [-1,1];
            app.UIAxes_2.YLim = [-1,1];
            app.UIAxes_3.YLim = [-1,1];
            app.UIAxes_4.YLim = [-1,1];
            app.UIAxes_5.YLim = [-1,1];
            app.UIAxes_6.YLim = [-1,1];
            app.UIAxes_7.YLim = [-1,1];
            app.UIAxes_8.YLim = [-1,1];
        end

        % Button pushed function: LoadMVCButton
        function LoadMVCButtonPushed(app, event)
            if app.SubjectNameEditField.Value
                app.TextArea_2.Visible = 'off';
                name = app.SubjectNameEditField.Value;
                folder_name = fullfile("\Users\lab-admin\Desktop\Rebecca\" + name);
                fileName = fullfile(folder_name + '\' + "MVC_info.mat");
                if isfile(fileName)
                    load(fileName);
                    app.FlexionMVCEditField.Value = MVCTable(1);
                    app.ExtenMVCEditField_2.Value = MVCTable(2);
                    app.UDeviationMVCEditField.Value = MVCTable(3);
                    app.RDeviationMVCEditField.Value = MVCTable(4);
                    app.deg_45EditField.Value = MVCTable(5);
                    app.deg_135EditField.Value = MVCTable(6);
                    app.deg_225EditField.Value = MVCTable(7);
                    app.deg_315EditField.Value = MVCTable(8);
                else
                    disp("kek");
                end
            else
                app.TextArea_2.Visible = "on";
            end
        end

        % Value changed function: Switch_F
        function Switch_FValueChanged(app, event)
            value = app.Switch_F.Value;
            
            disp(value)
            name = app.SubjectNameEditField.Value;
            lbl = '270';
            folder_name = fullfile("\Users\lab-admin\Desktop\Rebecca\" + name + '\' + lbl);
            fds = fileDatastore(folder_name, 'FileExtensions', '.mat','ReadFcn', @importdata );
            list = readall(fds);
            numberOfSessions = length(fds.Files);
            sig = [];
            for i=1:numberOfSessions
                data = list{i};
                sig = [sig;data];
            end
            %NumOfWindows = round(length(sig)/40);
            Fs = 200;
            intention =[];
            mvc = app.FlexionMVCEditField.Value;
            y =[];  % 3d matrix of emg samples (samples,channels,numberOfWindos)
            for i = 1:8
                y(:,i,:) = buffer(sig(:,i),56);
            end
            for j = 1:length(y)
                window = abs(y(:,:,j));
                meanF = signalTimeFeatureExtractor("Mean", true, 'SampleRate', Fs);
                meanFDS = arrayDatastore(window,"IterationDimension",2);
                meanFDS = transform(meanFDS,@(x)meanF.extract(x{:}));
                meanFeatures = readall(meanFDS,"UseParallel",true);
                meanMVC= mean(meanFeatures);
                intention = [intention;meanMVC*100/mvc];
            end
            gestures = find(intention > 0.4);
            

            e1 = sig(:,1);
            e2 = sig(:,2);
            e3 = sig(:,3);
            e4 = sig(:,4);
            e5 = sig(:,5);
            e6 = sig(:,6);
            e7 = sig(:,7);
            e8 = sig(:,8);
            plot(app.UIAxes,  e1);
            plot(app.UIAxes_2,e2);
            plot(app.UIAxes_3,e3);
            plot(app.UIAxes_4,e4);
            plot(app.UIAxes_5,e5);
            plot(app.UIAxes_6,e6);
            plot(app.UIAxes_7,e7);
            plot(app.UIAxes_8,e8);
            xline(app.UIAxes,  (gestures-1)*56,'--r');
            xline(app.UIAxes_2,(gestures-1)*56,'--r');
            xline(app.UIAxes_3,(gestures-1)*56,'--r');
            xline(app.UIAxes_4,(gestures-1)*56,'--r');
            xline(app.UIAxes_5,(gestures-1)*56,'--r');
            xline(app.UIAxes_6,(gestures-1)*56,'--r');
            xline(app.UIAxes_7,(gestures-1)*56,'--r');
            xline(app.UIAxes_8,(gestures-1)*56,'--r');
            app.UIAxes.YLim =   [-1,1];
            app.UIAxes_2.YLim = [-1,1];
            app.UIAxes_3.YLim = [-1,1];
            app.UIAxes_4.YLim = [-1,1];
            app.UIAxes_5.YLim = [-1,1];
            app.UIAxes_6.YLim = [-1,1];
            app.UIAxes_7.YLim = [-1,1];
            app.UIAxes_8.YLim = [-1,1];
            whole_ROI = [];
            for k = 1:length(gestures)
                index = gestures(k);
                whole_ROI = [whole_ROI;y(:,:,index)];
            end   

            ecut1 = whole_ROI(:,1);
            ecut2 = whole_ROI(:,2);
            ecut3 = whole_ROI(:,3);
            ecut4 = whole_ROI(:,4);
            ecut5 = whole_ROI(:,5);
            ecut6 = whole_ROI(:,6);
            ecut7 = whole_ROI(:,7);
            ecut8 = whole_ROI(:,8);
            plot(app.UIAxes_9, ecut1);
            plot(app.UIAxes_10,ecut2);
            plot(app.UIAxes_11,ecut3);
            plot(app.UIAxes_12,ecut4);
            plot(app.UIAxes_13,ecut5);
            plot(app.UIAxes_14,ecut6);
            plot(app.UIAxes_15,ecut7);
            plot(app.UIAxes_16,ecut8);
            app.UIAxes_9.YLim =  [-1,1];
            app.UIAxes_10.YLim = [-1,1];
            app.UIAxes_11.YLim = [-1,1];
            app.UIAxes_12.YLim = [-1,1];
            app.UIAxes_13.YLim = [-1,1];
            app.UIAxes_14.YLim = [-1,1];
            app.UIAxes_15.YLim = [-1,1];
            app.UIAxes_16.YLim = [-1,1];
            app.whole_270 = whole_ROI;
        end

        % Value changed function: Switch_E
        function Switch_EValueChanged(app, event)
            %value = app.Switch_E.Value;            
            name = app.SubjectNameEditField.Value;
            lbl = '90';
            folder_name = fullfile("\Users\lab-admin\Desktop\Rebecca\" + name + '\' + lbl);
            fds = fileDatastore(folder_name, 'FileExtensions', '.mat','ReadFcn', @importdata );
            list = readall(fds);
            numberOfSessions = length(fds.Files);
            sig = [];
            for i=1:numberOfSessions
                data = list{i};
                sig = [sig;data];
            end
            %NumOfWindows = round(length(sig)/40);
            Fs = 200;
            intention =[];
            mvc = app.ExtenMVCEditField_2.Value;
            y =[];  % 3d matrix of emg samples (samples,channels,numberOfWindos)
            for i = 1:8
                y(:,i,:) = buffer(sig(:,i),56);
            end
            for j = 1:length(y)
                window = abs(y(:,:,j));
                meanF = signalTimeFeatureExtractor("Mean", true, 'SampleRate', Fs);
                meanFDS = arrayDatastore(window,"IterationDimension",2);
                meanFDS = transform(meanFDS,@(x)meanF.extract(x{:}));
                meanFeatures = readall(meanFDS,"UseParallel",true);
                meanMVC= mean(meanFeatures);
                intention = [intention; meanMVC*100/mvc];
            end
            gestures = find(intention > 0.4);

            e1 = sig(:,1);
            e2 = sig(:,2);
            e3 = sig(:,3);
            e4 = sig(:,4);
            e5 = sig(:,5);
            e6 = sig(:,6);
            e7 = sig(:,7);
            e8 = sig(:,8);
            plot(app.UIAxes,  e1);
            plot(app.UIAxes_2,e2);
            plot(app.UIAxes_3,e3);
            plot(app.UIAxes_4,e4);
            plot(app.UIAxes_5,e5);
            plot(app.UIAxes_6,e6);
            plot(app.UIAxes_7,e7);
            plot(app.UIAxes_8,e8);
            xline(app.UIAxes,  (gestures-1)*56,'--r');
            xline(app.UIAxes_2,(gestures-1)*56,'--r');
            xline(app.UIAxes_3,(gestures-1)*56,'--r');
            xline(app.UIAxes_4,(gestures-1)*56,'--r');
            xline(app.UIAxes_5,(gestures-1)*56,'--r');
            xline(app.UIAxes_6,(gestures-1)*56,'--r');
            xline(app.UIAxes_7,(gestures-1)*56,'--r');
            xline(app.UIAxes_8,(gestures-1)*56,'--r');
            app.UIAxes.YLim =   [-1,1];
            app.UIAxes_2.YLim = [-1,1];
            app.UIAxes_3.YLim = [-1,1];
            app.UIAxes_4.YLim = [-1,1];
            app.UIAxes_5.YLim = [-1,1];
            app.UIAxes_6.YLim = [-1,1];
            app.UIAxes_7.YLim = [-1,1];
            app.UIAxes_8.YLim = [-1,1];
            whole_ROI = [];
            for k = 1:length(gestures)
                index = gestures(k);
                whole_ROI = [whole_ROI;y(:,:,index)];
            end   

            ecut1 = whole_ROI(:,1);
            ecut2 = whole_ROI(:,2);
            ecut3 = whole_ROI(:,3);
            ecut4 = whole_ROI(:,4);
            ecut5 = whole_ROI(:,5);
            ecut6 = whole_ROI(:,6);
            ecut7 = whole_ROI(:,7);
            ecut8 = whole_ROI(:,8);
            plot(app.UIAxes_9, ecut1);
            plot(app.UIAxes_10,ecut2);
            plot(app.UIAxes_11,ecut3);
            plot(app.UIAxes_12,ecut4);
            plot(app.UIAxes_13,ecut5);
            plot(app.UIAxes_14,ecut6);
            plot(app.UIAxes_15,ecut7);
            plot(app.UIAxes_16,ecut8);
            app.UIAxes_9.YLim =  [-1,1];
            app.UIAxes_10.YLim = [-1,1];
            app.UIAxes_11.YLim = [-1,1];
            app.UIAxes_12.YLim = [-1,1];
            app.UIAxes_13.YLim = [-1,1];
            app.UIAxes_14.YLim = [-1,1];
            app.UIAxes_15.YLim = [-1,1];
            app.UIAxes_16.YLim = [-1,1];
            app.whole_90 = whole_ROI;
        end

        % Value changed function: Switch_U
        function Switch_UValueChanged(app, event)
            %value = app.Switch_U.Value;
            name = app.SubjectNameEditField.Value;
            lbl = '360';
            folder_name = fullfile("\Users\lab-admin\Desktop\Rebecca\" + name + '\' + lbl);
            fds = fileDatastore(folder_name, 'FileExtensions', '.mat','ReadFcn', @importdata );
            list = readall(fds);
            numberOfSessions = length(fds.Files);
            sig = [];
            for i=1:numberOfSessions
                data = list{i};
                sig = [sig;data];
            end
            %NumOfWindows = round(length(sig)/40);
            Fs = 200;
            intention =[];
            mvc = app.UDeviationMVCEditField.Value;
            y =[];  % 3d matrix of emg samples (samples,channels,numberOfWindos)
            for i = 1:8
                y(:,i,:) = buffer(sig(:,i),56);
            end
            for j = 1:length(y)
                window = abs(y(:,:,j));
                meanF = signalTimeFeatureExtractor("Mean", true, 'SampleRate', Fs);
                meanFDS = arrayDatastore(window,"IterationDimension",2);
                meanFDS = transform(meanFDS,@(x)meanF.extract(x{:}));
                meanFeatures = readall(meanFDS,"UseParallel",true);
                meanMVC= mean(meanFeatures);
                intention = [intention;meanMVC*100/mvc];
            end
            gestures = find(intention > 0.4);

            e1 = sig(:,1);
            e2 = sig(:,2);
            e3 = sig(:,3);
            e4 = sig(:,4);
            e5 = sig(:,5);
            e6 = sig(:,6);
            e7 = sig(:,7);
            e8 = sig(:,8);
            plot(app.UIAxes,  e1);
            plot(app.UIAxes_2,e2);
            plot(app.UIAxes_3,e3);
            plot(app.UIAxes_4,e4);
            plot(app.UIAxes_5,e5);
            plot(app.UIAxes_6,e6);
            plot(app.UIAxes_7,e7);
            plot(app.UIAxes_8,e8);
            xline(app.UIAxes,  (gestures-1)*56,'--r');
            xline(app.UIAxes_2,(gestures-1)*56,'--r');
            xline(app.UIAxes_3,(gestures-1)*56,'--r');
            xline(app.UIAxes_4,(gestures-1)*56,'--r');
            xline(app.UIAxes_5,(gestures-1)*56,'--r');
            xline(app.UIAxes_6,(gestures-1)*56,'--r');
            xline(app.UIAxes_7,(gestures-1)*56,'--r');
            xline(app.UIAxes_8,(gestures-1)*56,'--r');
            app.UIAxes.YLim =   [-1,1];
            app.UIAxes_2.YLim = [-1,1];
            app.UIAxes_3.YLim = [-1,1];
            app.UIAxes_4.YLim = [-1,1];
            app.UIAxes_5.YLim = [-1,1];
            app.UIAxes_6.YLim = [-1,1];
            app.UIAxes_7.YLim = [-1,1];
            app.UIAxes_8.YLim = [-1,1];
            whole_ROI = [];
            for k = 1:length(gestures)
                index = gestures(k);
                whole_ROI = [whole_ROI;y(:,:,index)];
            end   
            ecut1 = whole_ROI(:,1);
            ecut2 = whole_ROI(:,2);
            ecut3 = whole_ROI(:,3);
            ecut4 = whole_ROI(:,4);
            ecut5 = whole_ROI(:,5);
            ecut6 = whole_ROI(:,6);
            ecut7 = whole_ROI(:,7);
            ecut8 = whole_ROI(:,8);
            plot(app.UIAxes_9, ecut1);
            plot(app.UIAxes_10,ecut2);
            plot(app.UIAxes_11,ecut3);
            plot(app.UIAxes_12,ecut4);
            plot(app.UIAxes_13,ecut5);
            plot(app.UIAxes_14,ecut6);
            plot(app.UIAxes_15,ecut7);
            plot(app.UIAxes_16,ecut8);
            app.UIAxes_9.YLim =  [-1,1];
            app.UIAxes_10.YLim = [-1,1];
            app.UIAxes_11.YLim = [-1,1];
            app.UIAxes_12.YLim = [-1,1];
            app.UIAxes_13.YLim = [-1,1];
            app.UIAxes_14.YLim = [-1,1];
            app.UIAxes_15.YLim = [-1,1];
            app.UIAxes_16.YLim = [-1,1];
            app.whole_360 = whole_ROI;
        end

        % Value changed function: Switch_R
        function Switch_RValueChanged(app, event)
            %value = app.Switch_R.Value;
            name = app.SubjectNameEditField.Value;
            lbl = '180';
            folder_name = fullfile("\Users\lab-admin\Desktop\Rebecca\" + name + '\' + lbl);
            fds = fileDatastore(folder_name, 'FileExtensions', '.mat','ReadFcn', @importdata );
            list = readall(fds);
            numberOfSessions = length(fds.Files);
            sig = [];
            for i=1:numberOfSessions
                data = list{i};
                sig = [sig;data];
            end
            %NumOfWindows = round(length(sig)/40);
            Fs = 200;
            intention =[];
            mvc = app.RDeviationMVCEditField.Value;
            y =[];  % 3D matrix of emg samples (samples,channels,numberOfWindos)
            for i = 1:8
                y(:,i,:) = buffer(sig(:,i),56);
            end
            for j = 1:length(y)
                window = abs(y(:,:,j));
                meanF = signalTimeFeatureExtractor("Mean", true, 'SampleRate', Fs);
                meanFDS = arrayDatastore(window,"IterationDimension",2);
                meanFDS = transform(meanFDS,@(x)meanF.extract(x{:}));
                meanFeatures = readall(meanFDS,"UseParallel",true);
                meanMVC= mean(meanFeatures);
                intention = [intention;meanMVC*100/mvc];
            end
            gestures = find(intention > 0.4);

            e1 = sig(:,1);
            e2 = sig(:,2);
            e3 = sig(:,3);
            e4 = sig(:,4);
            e5 = sig(:,5);
            e6 = sig(:,6);
            e7 = sig(:,7);
            e8 = sig(:,8);
            plot(app.UIAxes,  e1);
            plot(app.UIAxes_2,e2);
            plot(app.UIAxes_3,e3);
            plot(app.UIAxes_4,e4);
            plot(app.UIAxes_5,e5);
            plot(app.UIAxes_6,e6);
            plot(app.UIAxes_7,e7);
            plot(app.UIAxes_8,e8);
            xline(app.UIAxes,  (gestures-1)*56,'--r');
            xline(app.UIAxes_2,(gestures-1)*56,'--r');
            xline(app.UIAxes_3,(gestures-1)*56,'--r');
            xline(app.UIAxes_4,(gestures-1)*56,'--r');
            xline(app.UIAxes_5,(gestures-1)*56,'--r');
            xline(app.UIAxes_6,(gestures-1)*56,'--r');
            xline(app.UIAxes_7,(gestures-1)*56,'--r');
            xline(app.UIAxes_8,(gestures-1)*56,'--r');
            app.UIAxes.YLim =   [-1,1];
            app.UIAxes_2.YLim = [-1,1];
            app.UIAxes_3.YLim = [-1,1];
            app.UIAxes_4.YLim = [-1,1];
            app.UIAxes_5.YLim = [-1,1];
            app.UIAxes_6.YLim = [-1,1];
            app.UIAxes_7.YLim = [-1,1];
            app.UIAxes_8.YLim = [-1,1];
            whole_ROI = [];
            for k = 1:length(gestures)
                index = gestures(k);
                whole_ROI = [whole_ROI;y(:,:,index)];
            end   

            ecut1 = whole_ROI(:,1);
            ecut2 = whole_ROI(:,2);
            ecut3 = whole_ROI(:,3);
            ecut4 = whole_ROI(:,4);
            ecut5 = whole_ROI(:,5);
            ecut6 = whole_ROI(:,6);
            ecut7 = whole_ROI(:,7);
            ecut8 = whole_ROI(:,8);
            plot(app.UIAxes_9, ecut1);
            plot(app.UIAxes_10,ecut2);
            plot(app.UIAxes_11,ecut3);
            plot(app.UIAxes_12,ecut4);
            plot(app.UIAxes_13,ecut5);
            plot(app.UIAxes_14,ecut6);
            plot(app.UIAxes_15,ecut7);
            plot(app.UIAxes_16,ecut8);
            app.UIAxes_9.YLim =  [-1,1];
            app.UIAxes_10.YLim = [-1,1];
            app.UIAxes_11.YLim = [-1,1];
            app.UIAxes_12.YLim = [-1,1];
            app.UIAxes_13.YLim = [-1,1];
            app.UIAxes_14.YLim = [-1,1];
            app.UIAxes_15.YLim = [-1,1];
            app.UIAxes_16.YLim = [-1,1];
            app.whole_180 = whole_ROI;
        end

        % Value changed function: Switch_Rest
        function Switch_RestValueChanged(app, event)
            %value = app.Switch_Rest.Value;
            name = app.SubjectNameEditField.Value;
            lbl = '0';
            folder_name = fullfile("\Users\lab-admin\Desktop\Rebecca\" + name + '\' + lbl);
            fds = fileDatastore(folder_name, 'FileExtensions', '.mat','ReadFcn', @importdata );
            list = readall(fds);
            numberOfSessions = length(fds.Files);
            sig = [];
            for i=1:numberOfSessions
                data = list{i};
                sig = [sig;data];
            end
            %NumOfWindows = round(length(sig)/40);
            Fs = 200;
            intention =[];
            mvc = 1;
            y =[];  % 3d matrix of emg samples (samples,channels,numberOfWindos)
            for i = 1:8
                y(:,i,:) = buffer(sig(:,i),56);
            end
            for j = 1:length(y)
                window = abs(y(:,:,j));
                meanF = signalTimeFeatureExtractor("Mean", true, 'SampleRate', Fs);
                meanFDS = arrayDatastore(window,"IterationDimension",2);
                meanFDS = transform(meanFDS,@(x)meanF.extract(x{:}));
                meanFeatures = readall(meanFDS,"UseParallel",true);
                meanMVC= mean(meanFeatures);
                intention = [intention;meanMVC/mvc];
            end
            gestures = find(intention < 0.019);

            e1 = sig(:,1);
            e2 = sig(:,2);
            e3 = sig(:,3);
            e4 = sig(:,4);
            e5 = sig(:,5);
            e6 = sig(:,6);
            e7 = sig(:,7);
            e8 = sig(:,8);
            plot(app.UIAxes,  e1);
            plot(app.UIAxes_2,e2);
            plot(app.UIAxes_3,e3);
            plot(app.UIAxes_4,e4);
            plot(app.UIAxes_5,e5);
            plot(app.UIAxes_6,e6);
            plot(app.UIAxes_7,e7);
            plot(app.UIAxes_8,e8);
            xline(app.UIAxes,  (gestures-1)*56,'--r');
            xline(app.UIAxes_2,(gestures-1)*56,'--r');
            xline(app.UIAxes_3,(gestures-1)*56,'--r');
            xline(app.UIAxes_4,(gestures-1)*56,'--r');
            xline(app.UIAxes_5,(gestures-1)*56,'--r');
            xline(app.UIAxes_6,(gestures-1)*56,'--r');
            xline(app.UIAxes_7,(gestures-1)*56,'--r');
            xline(app.UIAxes_8,(gestures-1)*56,'--r');
            app.UIAxes.YLim =   [-1,1];
            app.UIAxes_2.YLim = [-1,1];
            app.UIAxes_3.YLim = [-1,1];
            app.UIAxes_4.YLim = [-1,1];
            app.UIAxes_5.YLim = [-1,1];
            app.UIAxes_6.YLim = [-1,1];
            app.UIAxes_7.YLim = [-1,1];
            app.UIAxes_8.YLim = [-1,1];
            whole_ROI = [];
            for k = 1:length(gestures)
                index = gestures(k);
                whole_ROI = [whole_ROI;y(:,:,index)];
            end   

            ecut1 = whole_ROI(:,1);
            ecut2 = whole_ROI(:,2);
            ecut3 = whole_ROI(:,3);
            ecut4 = whole_ROI(:,4);
            ecut5 = whole_ROI(:,5);
            ecut6 = whole_ROI(:,6);
            ecut7 = whole_ROI(:,7);
            ecut8 = whole_ROI(:,8);
            plot(app.UIAxes_9, ecut1);
            plot(app.UIAxes_10,ecut2);
            plot(app.UIAxes_11,ecut3);
            plot(app.UIAxes_12,ecut4);
            plot(app.UIAxes_13,ecut5);
            plot(app.UIAxes_14,ecut6);
            plot(app.UIAxes_15,ecut7);
            plot(app.UIAxes_16,ecut8);
            app.UIAxes_9.YLim =  [-1,1];
            app.UIAxes_10.YLim = [-1,1];
            app.UIAxes_11.YLim = [-1,1];
            app.UIAxes_12.YLim = [-1,1];
            app.UIAxes_13.YLim = [-1,1];
            app.UIAxes_14.YLim = [-1,1];
            app.UIAxes_15.YLim = [-1,1];
            app.UIAxes_16.YLim = [-1,1];
            app.whole_0 = whole_ROI;
        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
            name = app.SubjectNameEditField.Value;
            if app.CheckBox_E.Value == 1
                lbl = '90';
                folder_name = fullfile("\Users\lab-admin\Desktop\Rebecca\" + name + '\' + lbl);
                whole_ROI = app.whole_90;
                partision = [];
                for j = 1:8
                    partision(:,j,:) = buffer(whole_ROI(:,j),56,28);
                end

                for i = 1:length(partision)
                    img_name = sprintf('Sample%dExtention.jpeg', i);
                    file_name = fullfile(folder_name + '\' + img_name);
                    img_matrix = abs(partision(:,:,i));
                    [img_envelope,~] = envelope(img_matrix,5);
                    img_resize = repelem(img_envelope,1,7);                  
                    imwrite(img_resize,file_name);
                end
            end
            if app.CheckBox_F.Value == 1
                lbl = '270';
                folder_name = fullfile("\Users\lab-admin\Desktop\Rebecca\" + name + '\' + lbl); 
                whole_ROI = app.whole_270;
                partision = [];
                for j = 1:8
                    partision(:,j,:) = buffer(whole_ROI(:,j),56,28);
                end

                for i = 1:length(partision)
                    img_name = sprintf('Sample%dFlexion.jpeg', i);
                    file_name = fullfile(folder_name + '\' + img_name);
                    img_matrix = abs(partision(:,:,i));
                    [img_envelope,~] = envelope(img_matrix,5);
                    img_resize = repelem(img_envelope,1,7);
                    imwrite(img_resize,file_name);
                end
            end
            if app.CheckBox_R.Value == 1
                lbl = '180';
                folder_name = fullfile("\Users\lab-admin\Desktop\Rebecca\" + name + '\' + lbl);
                whole_ROI = app.whole_180;
                partision = [];
                for j = 1:8
                    partision(:,j,:) = buffer(whole_ROI(:,j),56,28);
                end

                for i = 1:length(partision)
                    img_name = sprintf('Sample%dRDeviation.jpeg', i);
                    file_name = fullfile(folder_name + '\' + img_name);
                    img_matrix = abs(partision(:,:,i));
                    [img_envelope,~] = envelope(img_matrix,5);
                    img_resize = repelem(img_envelope,1,7);
                    imwrite(img_resize,file_name);
                end
            end
            if app.CheckBox_U.Value == 1
                lbl = '360';
                folder_name = fullfile("\Users\lab-admin\Desktop\Rebecca\" + name + '\' + lbl);
                whole_ROI = app.whole_360;
                partision = [];
                for j = 1:8
                    partision(:,j,:) = buffer(whole_ROI(:,j),56,28);
                end

                for i = 1:length(partision)
                    img_name = sprintf('Sample%dUDeviation.jpeg', i);
                    file_name = fullfile(folder_name + '\' + img_name);
                    img_matrix = abs(partision(:,:,i));
                    [img_envelope,~] = envelope(img_matrix,5);
                    img_resize = repelem(img_envelope,1,7);
                    imwrite(img_resize,file_name);
                end
            end
            if app.CheckBox_45.Value == 1
                lbl = '45';
                folder_name = fullfile("\Users\lab-admin\Desktop\Rebecca\" + name + '\' + lbl);
                whole_ROI = app.whole_45;
                partision = [];
                for j = 1:8
                    partision(:,j,:) = buffer(whole_ROI(:,j),56,28);
                end

                for i = 1:length(partision)
                    img_name = sprintf('Sample%dExtention.jpeg', i);
                    file_name = fullfile(folder_name + '\' + img_name);
                    img_matrix = abs(partision(:,:,i));
                    [img_envelope,~] = envelope(img_matrix,5);
                    img_resize = repelem(img_envelope,1,7);
                    imwrite(img_resize,file_name);
                end
            end
            if app.CheckBox_135.Value == 1
                lbl = '135';
                folder_name = fullfile("\Users\lab-admin\Desktop\Rebecca\" + name + '\' + lbl);
                whole_ROI = app.whole_135;
                partision = [];
                for j = 1:8
                    partision(:,j,:) = buffer(whole_ROI(:,j),56,28);
                end

                for i = 1:length(partision)
                    img_name = sprintf('Sample%dExtention.jpeg', i);
                    file_name = fullfile(folder_name + '\' + img_name);
                    img_matrix = abs(partision(:,:,i));
                    [img_envelope,~] = envelope(img_matrix,5);
                    img_resize = repelem(img_envelope,1,7);
                    imwrite(img_resize,file_name);
                end
            end
            if app.CheckBox_225.Value == 1
                lbl = '225';
                folder_name = fullfile("\Users\lab-admin\Desktop\Rebecca\" + name + '\' + lbl);
                whole_ROI = app.whole_225;
                partision = [];
                for j = 1:8
                    partision(:,j,:) = buffer(whole_ROI(:,j),56,28);
                end

                for i = 1:length(partision)
                    img_name = sprintf('Sample%dExtention.jpeg', i);
                    file_name = fullfile(folder_name + '\' + img_name);
                    img_matrix = abs(partision(:,:,i));
                    [img_envelope,~] = envelope(img_matrix,5);
                    img_resize = repelem(img_envelope,1,7);                  
                    imwrite(img_resize,file_name);
                end
            end     
            if app.CheckBox_315.Value == 1
                lbl = '315';
                folder_name = fullfile("\Users\lab-admin\Desktop\Rebecca\" + name + '\' + lbl);
                whole_ROI = app.whole_315;
                partision = [];
                for j = 1:8
                    partision(:,j,:) = buffer(whole_ROI(:,j),56,28);
                end

                for i = 1:length(partision)
                    img_name = sprintf('Sample%dExtention.jpeg', i);
                    file_name = fullfile(folder_name + '\' + img_name);
                    img_matrix = abs(partision(:,:,i));
                    [img_envelope,~] = envelope(img_matrix,5);
                    img_resize = repelem(img_envelope,1,7);                  
                    imwrite(img_resize,file_name);
                end
            end
            if app.CheckBox_Rest.Value == 1
                lbl = '0';
                folder_name = fullfile("\Users\lab-admin\Desktop\Rebecca\" + name + '\' + lbl); 
                whole_ROI = app.whole_0;
                partision = [];
                for j = 1:8
                    partision(:,j,:) = buffer(whole_ROI(:,j),56,28);
                end

                for i = 1:length(partision)
                    img_name = sprintf('Sample%dRest.jpeg', i);
                    file_name = fullfile(folder_name + '\' + img_name);
                    img_matrix = abs(partision(:,:,i));
                    [img_envelope,~] = envelope(img_matrix,5);
                    img_resize = repelem(img_envelope,1,7);
                    imwrite(img_resize,file_name);
                end
            end
        end

        % Value changed function: Switch_45
        function Switch_45ValueChanged(app, event)

            name = app.SubjectNameEditField.Value;
            lbl = '45';
            folder_name = fullfile("\Users\lab-admin\Desktop\Rebecca\" + name + '\' + lbl);
            fds = fileDatastore(folder_name, 'FileExtensions', '.mat','ReadFcn', @importdata );
            list = readall(fds);
            numberOfSessions = length(fds.Files);
            sig = [];
            for i=1:numberOfSessions
                data = list{i};
                sig = [sig;data];
            end
            %NumOfWindows = round(length(sig)/40);
            Fs = 200;
            intention =[];
            mvc = app.deg_45EditField.Value;
            y =[];  % 3D matrix of emg samples (samples,channels,numberOfWindos)
            for i = 1:8
                y(:,i,:) = buffer(sig(:,i),56);
            end
            for j = 1:length(y)
                window = abs(y(:,:,j));
                meanF = signalTimeFeatureExtractor("Mean", true, 'SampleRate', Fs);
                meanFDS = arrayDatastore(window,"IterationDimension",2);
                meanFDS = transform(meanFDS,@(x)meanF.extract(x{:}));
                meanFeatures = readall(meanFDS,"UseParallel",true);
                meanMVC= mean(meanFeatures);
                intention = [intention;meanMVC*100/mvc];
            end
            gestures = find(intention > 0.4);

            e1 = sig(:,1);
            e2 = sig(:,2);
            e3 = sig(:,3);
            e4 = sig(:,4);
            e5 = sig(:,5);
            e6 = sig(:,6);
            e7 = sig(:,7);
            e8 = sig(:,8);
            plot(app.UIAxes,  e1);
            plot(app.UIAxes_2,e2);
            plot(app.UIAxes_3,e3);
            plot(app.UIAxes_4,e4);
            plot(app.UIAxes_5,e5);
            plot(app.UIAxes_6,e6);
            plot(app.UIAxes_7,e7);
            plot(app.UIAxes_8,e8);
            xline(app.UIAxes,  (gestures-1)*56,'--r');
            xline(app.UIAxes_2,(gestures-1)*56,'--r');
            xline(app.UIAxes_3,(gestures-1)*56,'--r');
            xline(app.UIAxes_4,(gestures-1)*56,'--r');
            xline(app.UIAxes_5,(gestures-1)*56,'--r');
            xline(app.UIAxes_6,(gestures-1)*56,'--r');
            xline(app.UIAxes_7,(gestures-1)*56,'--r');
            xline(app.UIAxes_8,(gestures-1)*56,'--r');
            app.UIAxes.YLim =   [-1,1];
            app.UIAxes_2.YLim = [-1,1];
            app.UIAxes_3.YLim = [-1,1];
            app.UIAxes_4.YLim = [-1,1];
            app.UIAxes_5.YLim = [-1,1];
            app.UIAxes_6.YLim = [-1,1];
            app.UIAxes_7.YLim = [-1,1];
            app.UIAxes_8.YLim = [-1,1];
            whole_ROI = [];
            for k = 1:length(gestures)
                index = gestures(k);
                whole_ROI = [whole_ROI;y(:,:,index)];
            end   

            ecut1 = whole_ROI(:,1);
            ecut2 = whole_ROI(:,2);
            ecut3 = whole_ROI(:,3);
            ecut4 = whole_ROI(:,4);
            ecut5 = whole_ROI(:,5);
            ecut6 = whole_ROI(:,6);
            ecut7 = whole_ROI(:,7);
            ecut8 = whole_ROI(:,8);
            plot(app.UIAxes_9, ecut1);
            plot(app.UIAxes_10,ecut2);
            plot(app.UIAxes_11,ecut3);
            plot(app.UIAxes_12,ecut4);
            plot(app.UIAxes_13,ecut5);
            plot(app.UIAxes_14,ecut6);
            plot(app.UIAxes_15,ecut7);
            plot(app.UIAxes_16,ecut8);
            app.UIAxes_9.YLim =  [-1,1];
            app.UIAxes_10.YLim = [-1,1];
            app.UIAxes_11.YLim = [-1,1];
            app.UIAxes_12.YLim = [-1,1];
            app.UIAxes_13.YLim = [-1,1];
            app.UIAxes_14.YLim = [-1,1];
            app.UIAxes_15.YLim = [-1,1];
            app.UIAxes_16.YLim = [-1,1];
            app.whole_45 = whole_ROI;
            
        end

        % Value changed function: Switch_135
        function Switch_135ValueChanged(app, event)

            name = app.SubjectNameEditField.Value;
            lbl = '135';
            folder_name = fullfile("\Users\lab-admin\Desktop\Rebecca\" + name + '\' + lbl);
            fds = fileDatastore(folder_name, 'FileExtensions', '.mat','ReadFcn', @importdata );
            list = readall(fds);
            numberOfSessions = length(fds.Files);
            sig = [];
            for i=1:numberOfSessions
                data = list{i};
                sig = [sig;data];
            end
            %NumOfWindows = round(length(sig)/40);
            Fs = 200;
            intention =[];
            mvc = app.deg_135EditField.Value;
            y =[];  % 3D matrix of emg samples (samples,channels,numberOfWindos)
            for i = 1:8
                y(:,i,:) = buffer(sig(:,i),56);
            end
            for j = 1:length(y)
                window = abs(y(:,:,j));
                meanF = signalTimeFeatureExtractor("Mean", true, 'SampleRate', Fs);
                meanFDS = arrayDatastore(window,"IterationDimension",2);
                meanFDS = transform(meanFDS,@(x)meanF.extract(x{:}));
                meanFeatures = readall(meanFDS,"UseParallel",true);
                meanMVC= mean(meanFeatures);
                intention = [intention;meanMVC*100/mvc];
            end
            gestures = find(intention > 0.4);

            e1 = sig(:,1);
            e2 = sig(:,2);
            e3 = sig(:,3);
            e4 = sig(:,4);
            e5 = sig(:,5);
            e6 = sig(:,6);
            e7 = sig(:,7);
            e8 = sig(:,8);
            plot(app.UIAxes,  e1);
            plot(app.UIAxes_2,e2);
            plot(app.UIAxes_3,e3);
            plot(app.UIAxes_4,e4);
            plot(app.UIAxes_5,e5);
            plot(app.UIAxes_6,e6);
            plot(app.UIAxes_7,e7);
            plot(app.UIAxes_8,e8);
            xline(app.UIAxes,  (gestures-1)*56,'--r');
            xline(app.UIAxes_2,(gestures-1)*56,'--r');
            xline(app.UIAxes_3,(gestures-1)*56,'--r');
            xline(app.UIAxes_4,(gestures-1)*56,'--r');
            xline(app.UIAxes_5,(gestures-1)*56,'--r');
            xline(app.UIAxes_6,(gestures-1)*56,'--r');
            xline(app.UIAxes_7,(gestures-1)*56,'--r');
            xline(app.UIAxes_8,(gestures-1)*56,'--r');
            app.UIAxes.YLim =   [-1,1];
            app.UIAxes_2.YLim = [-1,1];
            app.UIAxes_3.YLim = [-1,1];
            app.UIAxes_4.YLim = [-1,1];
            app.UIAxes_5.YLim = [-1,1];
            app.UIAxes_6.YLim = [-1,1];
            app.UIAxes_7.YLim = [-1,1];
            app.UIAxes_8.YLim = [-1,1];
            whole_ROI = [];
            for k = 1:length(gestures)
                index = gestures(k);
                whole_ROI = [whole_ROI;y(:,:,index)];
            end   

            ecut1 = whole_ROI(:,1);
            ecut2 = whole_ROI(:,2);
            ecut3 = whole_ROI(:,3);
            ecut4 = whole_ROI(:,4);
            ecut5 = whole_ROI(:,5);
            ecut6 = whole_ROI(:,6);
            ecut7 = whole_ROI(:,7);
            ecut8 = whole_ROI(:,8);
            plot(app.UIAxes_9, ecut1);
            plot(app.UIAxes_10,ecut2);
            plot(app.UIAxes_11,ecut3);
            plot(app.UIAxes_12,ecut4);
            plot(app.UIAxes_13,ecut5);
            plot(app.UIAxes_14,ecut6);
            plot(app.UIAxes_15,ecut7);
            plot(app.UIAxes_16,ecut8);
            app.UIAxes_9.YLim =  [-1,1];
            app.UIAxes_10.YLim = [-1,1];
            app.UIAxes_11.YLim = [-1,1];
            app.UIAxes_12.YLim = [-1,1];
            app.UIAxes_13.YLim = [-1,1];
            app.UIAxes_14.YLim = [-1,1];
            app.UIAxes_15.YLim = [-1,1];
            app.UIAxes_16.YLim = [-1,1];
            app.whole_135 = whole_ROI;
        end

        % Value changed function: Switch_225
        function Switch_225ValueChanged(app, event)
            %value = app.Switch_R.Value;
            name = app.SubjectNameEditField.Value;
            lbl = '225';
            folder_name = fullfile("\Users\lab-admin\Desktop\Rebecca\" + name + '\' + lbl);
            fds = fileDatastore(folder_name, 'FileExtensions', '.mat','ReadFcn', @importdata );
            list = readall(fds);
            numberOfSessions = length(fds.Files);
            sig = [];
            for i=1:numberOfSessions
                data = list{i};
                sig = [sig;data];
            end
            %NumOfWindows = round(length(sig)/40);
            Fs = 200;
            intention =[];
            mvc = app.deg_225EditField.Value;
            y =[];  % 3D matrix of emg samples (samples,channels,numberOfWindos)
            for i = 1:8
                y(:,i,:) = buffer(sig(:,i),56);
            end
            for j = 1:length(y)
                window = abs(y(:,:,j));
                meanF = signalTimeFeatureExtractor("Mean", true, 'SampleRate', Fs);
                meanFDS = arrayDatastore(window,"IterationDimension",2);
                meanFDS = transform(meanFDS,@(x)meanF.extract(x{:}));
                meanFeatures = readall(meanFDS,"UseParallel",true);
                meanMVC= mean(meanFeatures);
                intention = [intention;meanMVC*100/mvc];
            end
            gestures = find(intention > 0.4);

            e1 = sig(:,1);
            e2 = sig(:,2);
            e3 = sig(:,3);
            e4 = sig(:,4);
            e5 = sig(:,5);
            e6 = sig(:,6);
            e7 = sig(:,7);
            e8 = sig(:,8);
            plot(app.UIAxes,  e1);
            plot(app.UIAxes_2,e2);
            plot(app.UIAxes_3,e3);
            plot(app.UIAxes_4,e4);
            plot(app.UIAxes_5,e5);
            plot(app.UIAxes_6,e6);
            plot(app.UIAxes_7,e7);
            plot(app.UIAxes_8,e8);
            xline(app.UIAxes,  (gestures-1)*56,'--r');
            xline(app.UIAxes_2,(gestures-1)*56,'--r');
            xline(app.UIAxes_3,(gestures-1)*56,'--r');
            xline(app.UIAxes_4,(gestures-1)*56,'--r');
            xline(app.UIAxes_5,(gestures-1)*56,'--r');
            xline(app.UIAxes_6,(gestures-1)*56,'--r');
            xline(app.UIAxes_7,(gestures-1)*56,'--r');
            xline(app.UIAxes_8,(gestures-1)*56,'--r');
            app.UIAxes.YLim =   [-1,1];
            app.UIAxes_2.YLim = [-1,1];
            app.UIAxes_3.YLim = [-1,1];
            app.UIAxes_4.YLim = [-1,1];
            app.UIAxes_5.YLim = [-1,1];
            app.UIAxes_6.YLim = [-1,1];
            app.UIAxes_7.YLim = [-1,1];
            app.UIAxes_8.YLim = [-1,1];
            whole_ROI = [];
            for k = 1:length(gestures)
                index = gestures(k);
                whole_ROI = [whole_ROI;y(:,:,index)];
            end   

            ecut1 = whole_ROI(:,1);
            ecut2 = whole_ROI(:,2);
            ecut3 = whole_ROI(:,3);
            ecut4 = whole_ROI(:,4);
            ecut5 = whole_ROI(:,5);
            ecut6 = whole_ROI(:,6);
            ecut7 = whole_ROI(:,7);
            ecut8 = whole_ROI(:,8);
            plot(app.UIAxes_9, ecut1);
            plot(app.UIAxes_10,ecut2);
            plot(app.UIAxes_11,ecut3);
            plot(app.UIAxes_12,ecut4);
            plot(app.UIAxes_13,ecut5);
            plot(app.UIAxes_14,ecut6);
            plot(app.UIAxes_15,ecut7);
            plot(app.UIAxes_16,ecut8);
            app.UIAxes_9.YLim =  [-1,1];
            app.UIAxes_10.YLim = [-1,1];
            app.UIAxes_11.YLim = [-1,1];
            app.UIAxes_12.YLim = [-1,1];
            app.UIAxes_13.YLim = [-1,1];
            app.UIAxes_14.YLim = [-1,1];
            app.UIAxes_15.YLim = [-1,1];
            app.UIAxes_16.YLim = [-1,1];
            app.whole_225 = whole_ROI;
            
        end

        % Value changed function: Switch_315
        function Switch_315ValueChanged(app, event)
                      %value = app.Switch_R.Value;
            name = app.SubjectNameEditField.Value;
            lbl = '315';
            folder_name = fullfile("\Users\lab-admin\Desktop\Rebecca\" + name + '\' + lbl);
            fds = fileDatastore(folder_name, 'FileExtensions', '.mat','ReadFcn', @importdata );
            list = readall(fds);
            numberOfSessions = length(fds.Files);
            sig = [];
            for i=1:numberOfSessions
                data = list{i};
                sig = [sig;data];
            end
            %NumOfWindows = round(length(sig)/40);
            Fs = 200;
            intention =[];
            mvc = app.deg_315EditField.Value;
     
            y =[];  % 3D matrix of emg samples (samples,channels,numberOfWindos)
            for i = 1:8
                y(:,i,:) = buffer(sig(:,i),56);
            end
            for j = 1:length(y)
                window = abs(y(:,:,j));
                meanF = signalTimeFeatureExtractor("Mean", true, 'SampleRate', Fs);
                meanFDS = arrayDatastore(window,"IterationDimension",2);
                meanFDS = transform(meanFDS,@(x)meanF.extract(x{:}));
                meanFeatures = readall(meanFDS,"UseParallel",true);
                meanMVC= mean(meanFeatures);
                intention = [intention;meanMVC*100/mvc];
            end
            gestures = find(intention > 0.4);

            e1 = sig(:,1);
            e2 = sig(:,2);
            e3 = sig(:,3);
            e4 = sig(:,4);
            e5 = sig(:,5);
            e6 = sig(:,6);
            e7 = sig(:,7);
            e8 = sig(:,8);
            plot(app.UIAxes,  e1);
            plot(app.UIAxes_2,e2);
            plot(app.UIAxes_3,e3);
            plot(app.UIAxes_4,e4);
            plot(app.UIAxes_5,e5);
            plot(app.UIAxes_6,e6);
            plot(app.UIAxes_7,e7);
            plot(app.UIAxes_8,e8);
            xline(app.UIAxes,  (gestures-1)*56,'--r');
            xline(app.UIAxes_2,(gestures-1)*56,'--r');
            xline(app.UIAxes_3,(gestures-1)*56,'--r');
            xline(app.UIAxes_4,(gestures-1)*56,'--r');
            xline(app.UIAxes_5,(gestures-1)*56,'--r');
            xline(app.UIAxes_6,(gestures-1)*56,'--r');
            xline(app.UIAxes_7,(gestures-1)*56,'--r');
            xline(app.UIAxes_8,(gestures-1)*56,'--r');
            app.UIAxes.YLim =   [-1,1];
            app.UIAxes_2.YLim = [-1,1];
            app.UIAxes_3.YLim = [-1,1];
            app.UIAxes_4.YLim = [-1,1];
            app.UIAxes_5.YLim = [-1,1];
            app.UIAxes_6.YLim = [-1,1];
            app.UIAxes_7.YLim = [-1,1];
            app.UIAxes_8.YLim = [-1,1];
            whole_ROI = [];
            for k = 1:length(gestures)
                index = gestures(k);
                whole_ROI = [whole_ROI;y(:,:,index)];
            end   

            ecut1 = whole_ROI(:,1);
            ecut2 = whole_ROI(:,2);
            ecut3 = whole_ROI(:,3);
            ecut4 = whole_ROI(:,4);
            ecut5 = whole_ROI(:,5);
            ecut6 = whole_ROI(:,6);
            ecut7 = whole_ROI(:,7);
            ecut8 = whole_ROI(:,8);
            plot(app.UIAxes_9, ecut1);
            plot(app.UIAxes_10,ecut2);
            plot(app.UIAxes_11,ecut3);
            plot(app.UIAxes_12,ecut4);
            plot(app.UIAxes_13,ecut5);
            plot(app.UIAxes_14,ecut6);
            plot(app.UIAxes_15,ecut7);
            plot(app.UIAxes_16,ecut8);
            app.UIAxes_9.YLim =  [-1,1];
            app.UIAxes_10.YLim = [-1,1];
            app.UIAxes_11.YLim = [-1,1];
            app.UIAxes_12.YLim = [-1,1];
            app.UIAxes_13.YLim = [-1,1];
            app.UIAxes_14.YLim = [-1,1];
            app.UIAxes_15.YLim = [-1,1];
            app.UIAxes_16.YLim = [-1,1];
            app.whole_315 = whole_ROI;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Ch.1')
            xlabel(app.UIAxes, 'Sample')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.Tab);
            title(app.UIAxes_2, 'Ch.2')
            xlabel(app.UIAxes_2, 'Sample')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.Tab);
            title(app.UIAxes_3, 'Ch.3')
            xlabel(app.UIAxes_3, 'Sample')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.Tab);
            title(app.UIAxes_4, 'Ch.4')
            xlabel(app.UIAxes_4, 'Sample')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.Tab);
            title(app.UIAxes_5, 'Ch.5')
            xlabel(app.UIAxes_5, 'Sample')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.Tab);
            title(app.UIAxes_6, 'Ch.6')
            xlabel(app.UIAxes_6, 'Sample')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_7 = uiaxes(app.Tab);
            title(app.UIAxes_7, 'Ch.7')
            xlabel(app.UIAxes_7, 'Sample')
            ylabel(app.UIAxes_7, 'Y')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_8 = uiaxes(app.Tab);
            title(app.UIAxes_8, 'Ch.8')
            xlabel(app.UIAxes_8, 'Sample')
            ylabel(app.UIAxes_8, 'Y')
            zlabel(app.UIAxes_8, 'Z')
            app.StartdisplayButton = uibutton(app.Tab, 'push');
            app.StartdisplayButton.ButtonPushedFcn = createCallbackFcn(app, @StartdisplayButtonPushed, true);
            app.StartdisplayButton.Text = 'Start display';
            app.StopDisplayButton = uibutton(app.Tab, 'state');
            app.StopDisplayButton.Text = 'Stop Display';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.MaximumValuntaryContractionGauge = uigauge(app.Tab2, 'circular');
            app.Image = uiimage(app.Tab2);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'flexion.jpg');
            app.HandGestureDropDownLabel = uilabel(app.Tab2);
            app.HandGestureDropDownLabel.Text = 'Hand Gesture';
            app.HandGestureDropDown = uidropdown(app.Tab2);
            app.HandGestureDropDown.Items = {'Flexion', 'Extension', 'Ulnar Deviation', 'Radial Deviation', '45 deg', '135 deg', '225 deg', '315 deg'};
            app.HandGestureDropDown.ValueChangedFcn = createCallbackFcn(app, @HandGestureDropDownValueChanged, true);
            app.HandGestureDropDown.Value = 'Flexion';
            app.EstimateMVCButton = uibutton(app.Tab2, 'push');
            app.EstimateMVCButton.ButtonPushedFcn = createCallbackFcn(app, @EstimateMVCButtonPushed, true);
            app.EstimateMVCButton.Text = 'Estimate MVC';
            app.MaximumValuntaryContractionGaugeLabel = uilabel(app.Tab2);
            app.MaximumValuntaryContractionGaugeLabel.Text = 'Maximum Valuntary Contraction';
            app.FlexionMVCEditFieldLabel = uilabel(app.Tab2);
            app.FlexionMVCEditFieldLabel.Text = 'Flexion MVC';
            app.FlexionMVCEditField = uieditfield(app.Tab2, 'numeric');
            app.FlexionMVCEditField.ValueChangedFcn = createCallbackFcn(app, @FlexionMVCEditFieldValueChanged, true);
            app.FlexionMVCEditField.Value = 32;
            app.ExtensionMVCLabel = uilabel(app.Tab2);
            app.ExtensionMVCLabel.Text = 'Extension MVC';
            app.ExtenMVCEditField_2 = uieditfield(app.Tab2, 'numeric');
            app.ExtenMVCEditField_2.ValueChangedFcn = createCallbackFcn(app, @ExtenMVCEditField_2ValueChanged, true);
            app.ExtenMVCEditField_2.Value = 32;
            app.SubjectNameEditFieldLabel = uilabel(app.Tab2);
            app.SubjectNameEditFieldLabel.Text = 'Subject Name';
            app.SubjectNameEditField = uieditfield(app.Tab2, 'text');
            app.TextArea_2 = uitextarea(app.Tab2);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Visible = 'off';
            app.TextArea_2.Value = {'Subject name is empty'};
            app.UDeviationMVCEditFieldLabel = uilabel(app.Tab2);
            app.UDeviationMVCEditFieldLabel.Text = 'U. Deviation MVC';
            app.UDeviationMVCEditField = uieditfield(app.Tab2, 'numeric');
            app.UDeviationMVCEditField.ValueChangedFcn = createCallbackFcn(app, @UDeviationMVCEditFieldValueChanged, true);
            app.UDeviationMVCEditField.Value = 20;
            app.SaveButton = uibutton(app.Tab2, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.RDeviationMVCEditFieldLabel = uilabel(app.Tab2);
            app.RDeviationMVCEditFieldLabel.Text = 'R. Deviation MVC';
            app.RDeviationMVCEditField = uieditfield(app.Tab2, 'numeric');
            app.RDeviationMVCEditField.ValueChangedFcn = createCallbackFcn(app, @RDeviationMVCEditFieldValueChanged, true);
            app.RDeviationMVCEditField.Value = 20;
            app.degEditFieldLabel = uilabel(app.Tab2);
            app.degEditFieldLabel.Text = '45 deg';
            app.deg_45EditField = uieditfield(app.Tab2, 'numeric');
            app.deg_45EditField.Value = 32;
            app.LoadMVCButton = uibutton(app.Tab2, 'push');
            app.LoadMVCButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMVCButtonPushed, true);
            app.LoadMVCButton.Text = 'Load MVC';
            app.degLabel = uilabel(app.Tab2);
            app.degLabel.Text = '135 deg';
            app.deg_135EditField = uieditfield(app.Tab2, 'numeric');
            app.deg_135EditField.Value = 32;
            app.degLabel_2 = uilabel(app.Tab2);
            app.degLabel_2.Text = '225 deg';
            app.deg_225EditField = uieditfield(app.Tab2, 'numeric');
            app.deg_225EditField.Value = 32;
            app.degLabel_3 = uilabel(app.Tab2);
            app.degLabel_3.Text = '315 deg';
            app.deg_315EditField = uieditfield(app.Tab2, 'numeric');
            app.deg_315EditField.Value = 32;
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.Tab3.ButtonDownFcn = createCallbackFcn(app, @Tab3ButtonDown, true);
            app.UIAxes2 = uiaxes(app.Tab3);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'Time(sec)')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Image_2 = uiimage(app.Tab3);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'flexion.jpg');
            app.TextArea = uitextarea(app.Tab3);
            app.TextArea.Editable = 'off';
            app.TextArea.Visible = 'off';
            app.TextArea.Value = {'Rest'};
            app.HandGestureDropDown_2Label = uilabel(app.Tab3);
            app.HandGestureDropDown_2Label.Text = 'Hand Gesture';
            app.HandGestureDropDown_2 = uidropdown(app.Tab3);
            app.HandGestureDropDown_2.Items = {'Flexion', 'Extension', 'Ulnar Deviation', 'Radial Deviation', '45 deg', '135 deg', '225 deg', '315 deg', 'Rest'};
            app.HandGestureDropDown_2.ValueChangedFcn = createCallbackFcn(app, @HandGestureDropDown_2ValueChanged2, true);
            app.HandGestureDropDown_2.Value = 'Flexion';
            app.TextArea_8 = uitextarea(app.Tab3);
            app.TextArea_8.Editable = 'off';
            app.TextArea_8.Value = {'Convert'};
            app.TextArea_9 = uitextarea(app.Tab3);
            app.TextArea_9.Editable = 'off';
            app.TextArea_9.Value = {'Save'};
            app.Switch_F = uiswitch(app.Tab3, 'slider');
            app.Switch_F.ValueChangedFcn = createCallbackFcn(app, @Switch_FValueChanged, true);
            app.TextArea_3 = uitextarea(app.Tab3);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'Flextion'};
            app.CheckBox_F = uicheckbox(app.Tab3);
            app.CheckBox_F.Text = '';
            app.Switch_E = uiswitch(app.Tab3, 'slider');
            app.Switch_E.ValueChangedFcn = createCallbackFcn(app, @Switch_EValueChanged, true);
            app.Switch_U = uiswitch(app.Tab3, 'slider');
            app.Switch_U.ValueChangedFcn = createCallbackFcn(app, @Switch_UValueChanged, true);
            app.CheckBox_E = uicheckbox(app.Tab3);
            app.CheckBox_E.Text = '';
            app.TextArea_4 = uitextarea(app.Tab3);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Value = {'Extension'};
            app.Switch_R = uiswitch(app.Tab3, 'slider');
            app.Switch_R.ValueChangedFcn = createCallbackFcn(app, @Switch_RValueChanged, true);
            app.CheckBox_U = uicheckbox(app.Tab3);
            app.CheckBox_U.Text = '';
            app.StartRecordingButton = uibutton(app.Tab3, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingButtonPushed3, true);
            app.StartRecordingButton.Text = 'Start Recording';
            app.DisplayButton = uibutton(app.Tab3, 'push');
            app.DisplayButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayButtonPushed, true);
            app.DisplayButton.Text = 'Display';
            app.Switch_Rest = uiswitch(app.Tab3, 'slider');
            app.Switch_Rest.ValueChangedFcn = createCallbackFcn(app, @Switch_RestValueChanged, true);
            app.TextArea_5 = uitextarea(app.Tab3);
            app.TextArea_5.Editable = 'off';
            app.TextArea_5.Value = {'U.Deviation'};
            app.TextArea_6 = uitextarea(app.Tab3);
            app.TextArea_6.Editable = 'off';
            app.TextArea_6.Value = {'R.Deviation'};
            app.SaveButton_2 = uibutton(app.Tab3, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.ConvertButton = uibutton(app.Tab3, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Text = 'Convert';
            app.TextArea_7 = uitextarea(app.Tab3);
            app.TextArea_7.Editable = 'off';
            app.TextArea_7.Value = {'Rest'};
            app.CheckBox_R = uicheckbox(app.Tab3);
            app.CheckBox_R.Text = '';
            app.CheckBox_Rest = uicheckbox(app.Tab3);
            app.CheckBox_Rest.Text = '';
            app.Switch_45 = uiswitch(app.Tab3, 'slider');
            app.Switch_45.ValueChangedFcn = createCallbackFcn(app, @Switch_45ValueChanged, true);
            app.Switch_135 = uiswitch(app.Tab3, 'slider');
            app.Switch_135.ValueChangedFcn = createCallbackFcn(app, @Switch_135ValueChanged, true);
            app.Switch_225 = uiswitch(app.Tab3, 'slider');
            app.Switch_225.ValueChangedFcn = createCallbackFcn(app, @Switch_225ValueChanged, true);
            app.Switch_315 = uiswitch(app.Tab3, 'slider');
            app.Switch_315.ValueChangedFcn = createCallbackFcn(app, @Switch_315ValueChanged, true);
            app.TextArea_10 = uitextarea(app.Tab3);
            app.TextArea_10.Editable = 'off';
            app.TextArea_10.Value = {'45 deg'};
            app.TextArea_11 = uitextarea(app.Tab3);
            app.TextArea_11.Editable = 'off';
            app.TextArea_11.Value = {'135 deg'};
            app.TextArea_12 = uitextarea(app.Tab3);
            app.TextArea_12.Editable = 'off';
            app.TextArea_12.Value = {'225 deg'};
            app.TextArea_13 = uitextarea(app.Tab3);
            app.TextArea_13.Editable = 'off';
            app.TextArea_13.Value = {'315 deg'};
            app.CheckBox_45 = uicheckbox(app.Tab3);
            app.CheckBox_45.Text = '';
            app.CheckBox_135 = uicheckbox(app.Tab3);
            app.CheckBox_135.Text = '';
            app.CheckBox_225 = uicheckbox(app.Tab3);
            app.CheckBox_225.Text = '';
            app.CheckBox_315 = uicheckbox(app.Tab3);
            app.CheckBox_315.Text = '';
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = 'Tab4';
            app.UIAxes_9 = uiaxes(app.Tab4);
            title(app.UIAxes_9, 'Ch.1')
            xlabel(app.UIAxes_9, 'Sample')
            ylabel(app.UIAxes_9, 'Y')
            zlabel(app.UIAxes_9, 'Z')
            app.UIAxes_10 = uiaxes(app.Tab4);
            title(app.UIAxes_10, 'Ch.1')
            xlabel(app.UIAxes_10, 'Sample')
            ylabel(app.UIAxes_10, 'Y')
            zlabel(app.UIAxes_10, 'Z')
            app.UIAxes_11 = uiaxes(app.Tab4);
            title(app.UIAxes_11, 'Ch.1')
            xlabel(app.UIAxes_11, 'Sample')
            ylabel(app.UIAxes_11, 'Y')
            zlabel(app.UIAxes_11, 'Z')
            app.UIAxes_12 = uiaxes(app.Tab4);
            title(app.UIAxes_12, 'Ch.1')
            xlabel(app.UIAxes_12, 'Sample')
            ylabel(app.UIAxes_12, 'Y')
            zlabel(app.UIAxes_12, 'Z')
            app.UIAxes_13 = uiaxes(app.Tab4);
            title(app.UIAxes_13, 'Ch.1')
            xlabel(app.UIAxes_13, 'Sample')
            ylabel(app.UIAxes_13, 'Y')
            zlabel(app.UIAxes_13, 'Z')
            app.UIAxes_14 = uiaxes(app.Tab4);
            title(app.UIAxes_14, 'Ch.1')
            xlabel(app.UIAxes_14, 'Sample')
            ylabel(app.UIAxes_14, 'Y')
            zlabel(app.UIAxes_14, 'Z')
            app.UIAxes_15 = uiaxes(app.Tab4);
            title(app.UIAxes_15, 'Ch.1')
            xlabel(app.UIAxes_15, 'Sample')
            ylabel(app.UIAxes_15, 'Y')
            zlabel(app.UIAxes_15, 'Z')
            app.UIAxes_16 = uiaxes(app.Tab4);
            title(app.UIAxes_16, 'Ch.1')
            xlabel(app.UIAxes_16, 'Sample')
            ylabel(app.UIAxes_16, 'Y')
            zlabel(app.UIAxes_16, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Copy_of_data_acquisition

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end