classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        StopButton  matlab.ui.control.StateButton
        SartButton  matlab.ui.control.StateButton
        UIAxes      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: StopButton
        function StopButtonValueChanged(app, event)
            value = app.StopButton.Value;
            mm = MyoMex();
            m1 = mm.myoData();
            Fs = 200;
            pause(1);
            e = m1.emg_log;
            time0 = length(e)/Fs;
            timespace = linspace(0,time0,length(e));
            hold(app.UIAxes,"on");
            % for i =1:10 % ~app.StopButton.Value
            for i = 1:10
                e = m1.emg_log;
                timeSample = length(e)/Fs-time0;
                sig = abs(e(end-39:end,:)); %200 ms time window just like the whole system
                meanF = signalTimeFeatureExtractor("Mean", true, 'SampleRate', Fs);
                meanFDS = arrayDatastore(sig,"IterationDimension",2);
                meanFDS = transform(meanFDS,@(x)meanF.extract(x{:}));
                meanFeatures = readall(meanFDS,"UseParallel",true);
                meanMVC = mean(meanFeatures);
                plot(app.UIAxes,timeSample,meanMVC);
            end
            mm.delete;
            clear('mm');

            %     % emgMvc= 100 * mean(meanFeatures);
            %     % switch app.HandGestureDropDown_2.Value
            %     %     case 'Flexion'
            %     %         mvc = app.flexionMVC;
            %     %         intentionValue = meanMVC/mvc*100;
            %     %     case 'Extension'
            %     %         mvc = app.extensionMVC;
            %     %         intentionValue = meanMVC/mvc*100;
            %     %     case 'Ulnar Deviation'
            %     %         mvc = app.UDeviationMVC;
            %     %         intentionValue = meanMVC/mvc*100;
            %     %     case 'Radial Deviation'
            %     %         mvc = app.RDeviationMVC;
            %     %         intentionValue = meanMVC/mvc*100;
            % end

   
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            % myx = [0,1,1.5,6.5,7,8];
            % myy = [0,0,0.8,0.8,0,0];
            % hold(app.UIAxes,"on");
            % plot(app.UIAxes,myx,myy);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YLim = [0 1.1];
            app.SartButton = uibutton(app.UIFigure, 'state');
            app.SartButton.Text = 'Sart';
            app.StopButton = uibutton(app.UIFigure, 'state');
            app.StopButton.ValueChangedFcn = createCallbackFcn(app, @StopButtonValueChanged, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end